classdef EditLicense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddLicenseUIFigure         matlab.ui.Figure
        DaysFromNowLabel           matlab.ui.control.Label
        ValidUntilDropDown         matlab.ui.control.DropDown
        OptionalSendlicenseinformationtotheassignedemailLabel  matlab.ui.control.Label
        OperationButton            matlab.ui.control.Button
        SendLicenseInfoCheckBox    matlab.ui.control.CheckBox
        ValidUntilDatePicker       matlab.ui.control.DatePicker
        ValidUntilDatePickerLabel  matlab.ui.control.Label
        UserEmailEditField         matlab.ui.control.EditField
        UserEmailEditFieldLabel    matlab.ui.control.Label
        LicenseKeyEditField        matlab.ui.control.EditField
        LicenseKeyEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        LicenseKey
        AssignedEmail
        ValidUntil
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Intent)
            centerAppWindow(app);
            app.MainApp = MainApp;
            switch Intent
                case 'add'
                    app.LicenseKey = licenseKeygen;
                    app.LicenseKeyEditField.Value = app.LicenseKey;            
                case 'edit'
            end

        end

        % Button pushed function: OperationButton
        function OperationButtonPushed(app, event)
            app.MainApp.AddedLicense = {app.LicenseKey, app.AssignedEmail, app.ValidUntil, ''};
            app.OperationButton.Icon = 'graphics/loading-icon.gif';
            drawnow;
            addLicense(app.MainApp);
            delete(app);
        end

        % Value changed function: UserEmailEditField
        function UserEmailEditFieldValueChanged(app, event)
            app.AssignedEmail = app.UserEmailEditField.Value;
           
        end

        % Value changed function: ValidUntilDatePicker
        function ValidUntilDatePickerValueChanged(app, event)
            ValidUntilDatetime = datetime(app.ValidUntilDatePicker.Value, Format = 'yyyy-MM-dd');
            app.ValidUntil = char(ValidUntilDatetime);     
            ValidDuration = floor(days(ValidUntilDatetime - datetime('today')));
            if ValidDuration < years(998)
                app.DaysFromNowLabel.Text = sprintf('The license will expire in %d days.', ValidDuration);
            else
                app.DaysFromNowLabel.Text = 'The license will never expire.';
            end
        end

        % Value changed function: ValidUntilDropDown
        function ValidUntilDropDownValueChanged(app, event)
            Selected = app.ValidUntilDropDown.Value;
            if strcmp(Selected, 'Unlimited')
                app.ValidUntilDatePicker.Value = datetime(datetime('today') + years(999), Format = 'yyyy-MM-dd');
                ValidUntilDatePickerValueChanged(app, event);
                return;
            end
            Number = str2double(Selected(1));
            if contains(Selected, 'year')
                app.ValidUntilDatePicker.Value = datetime(datetime('today') + years(Number), Format = 'yyyy-MM-dd');
            elseif contains(Selected, 'month')
                app.ValidUntilDatePicker.Value = datetime(datetime('today') + calmonths(Number), Format = 'yyyy-MM-dd');
            end                
            ValidUntilDatePickerValueChanged(app, event);
        end

        % Drop down opening function: ValidUntilDropDown
        function ValidUntilDropDownOpening(app, event)
            app.ValidUntilDropDown.Items(matches(app.ValidUntilDropDown.Items, '')) = [];
            app.ValidUntilDropDown.Value = '1 year';
            ValidUntilDropDownValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AddLicenseUIFigure = uifigure('Visible', 'off');
            app.AddLicenseUIFigure.Name = 'Add License';
            app.AddLicenseUIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'repo-key-icon.png');
            app.LicenseKeyEditFieldLabel = uilabel(app.AddLicenseUIFigure);
            app.LicenseKeyEditFieldLabel.Text = 'License Key';
            app.LicenseKeyEditField = uieditfield(app.AddLicenseUIFigure, 'text');
            app.LicenseKeyEditField.Editable = 'off';
            app.UserEmailEditFieldLabel = uilabel(app.AddLicenseUIFigure);
            app.UserEmailEditFieldLabel.Text = 'User Email';
            app.UserEmailEditField = uieditfield(app.AddLicenseUIFigure, 'text');
            app.UserEmailEditField.ValueChangedFcn = createCallbackFcn(app, @UserEmailEditFieldValueChanged, true);
            app.ValidUntilDatePickerLabel = uilabel(app.AddLicenseUIFigure);
            app.ValidUntilDatePickerLabel.Text = 'Valid Until';
            app.ValidUntilDatePicker = uidatepicker(app.AddLicenseUIFigure);
            app.ValidUntilDatePicker.ValueChangedFcn = createCallbackFcn(app, @ValidUntilDatePickerValueChanged, true);
            app.SendLicenseInfoCheckBox = uicheckbox(app.AddLicenseUIFigure);
            app.SendLicenseInfoCheckBox.Text = '';
            app.OperationButton = uibutton(app.AddLicenseUIFigure, 'push');
            app.OperationButton.ButtonPushedFcn = createCallbackFcn(app, @OperationButtonPushed, true);
            app.OperationButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-plus-outline.png');
            app.OperationButton.Text = 'Add License';
            app.OptionalSendlicenseinformationtotheassignedemailLabel = uilabel(app.AddLicenseUIFigure);
            app.OptionalSendlicenseinformationtotheassignedemailLabel.Text = '(Optional) Send license information to the email.';
            app.ValidUntilDropDown = uidropdown(app.AddLicenseUIFigure);
            app.ValidUntilDropDown.Items = {'', '1 month', '6 month', '1 year', '3 years', '5 years', 'Unlimited'};
            app.ValidUntilDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ValidUntilDropDownOpening, true);
            app.ValidUntilDropDown.ValueChangedFcn = createCallbackFcn(app, @ValidUntilDropDownValueChanged, true);
            app.ValidUntilDropDown.Placeholder = 'Select duration';
            app.ValidUntilDropDown.Value = '';
            app.DaysFromNowLabel = uilabel(app.AddLicenseUIFigure);
            app.DaysFromNowLabel.Text = '';
            % Show the figure after all components are created
            app.AddLicenseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditLicense(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddLicenseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddLicenseUIFigure)
        end
    end
end