classdef LicenseManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClearCredentialListButton  matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        LastRetrieveLabel          matlab.ui.control.Label
        CredentialsDropDown        matlab.ui.control.DropDown
        LicensesPanel              matlab.ui.container.Panel
        RemoveLicenseButton_2      matlab.ui.control.Button
        LoadingImage               matlab.ui.control.Image
        LicensesUITable            matlab.ui.control.Table
        SearchLicenseButton        matlab.ui.control.Button
        SearchLicenseEditField     matlab.ui.control.EditField
        RemoveLicenseButton        matlab.ui.control.Button
        EditLicenseButton          matlab.ui.control.Button
        AddLicenseButton           matlab.ui.control.Button
        DatabasePanel              matlab.ui.container.Panel
        RepoCheckImage             matlab.ui.control.Image
        SaveCredentialsButton      matlab.ui.control.Button
        PATImage                   matlab.ui.control.Image
        RepoImage                  matlab.ui.control.Image
        PATEditField               matlab.ui.control.EditField
        RepositoryEditField        matlab.ui.control.EditField
        RetrieveDatabaseButton     matlab.ui.control.Button
        UpdateDatabaseButton       matlab.ui.control.Button
        CreateDatabaseButton       matlab.ui.control.Button
        ContextMenu                matlab.ui.container.ContextMenu
        PATHelp                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        UserName = ''
        RepoName = ''
        Token = ''
        DatabaseButtonGroup
        Credent
        LicTable
    end
    
    properties (Access = public)
        AddedLicense
        CredentialList
    end


    methods (Access = private)
        
        function Status = checkRepoStatus(app)
            Status = false;
            if any(matches({app.RepoName, app.UserName, app.Token}, ''))
                return;
            end
            Status = githubGet(app.UserName, app.RepoName, '', 'status', Token = app.Token);
            app.RepoCheckImage.Visible = 'on';
            app.RepoCheckImage.ImageSource = 'graphics/loading-icon.gif';
            if Status
                app.RepoCheckImage.ImageSource = 'graphics/check-circle.png';  
                app.SaveCredentialsButton.Enable = 'on';
                drawnow;
            else
                app.RepoCheckImage.ImageSource = 'graphics/alert-octagon.png';
                set([app.DatabaseButtonGroup, app.SaveCredentialsButton], Enable = 'off');
                app.LicensesUITable.Data = [];
                drawnow;
                return;
            end
            toggleRepoButtons(app)
        end

        function toggleRepoButtons(app)
            IsLicenseExist = githubGet(app.UserName, app.RepoName, 'license-database.csv', 'status', Token = app.Token);
            if ~IsLicenseExist
                app.CreateDatabaseButton.Enable = 'on';
            else
                app.RetrieveDatabaseButton.Enable = 'on';
                app.AddLicenseButton.Enable = 'on';
                retrieveDatabase(app);
            end
        end

        function retrieveDatabase(app)               
            app.LoadingImage.Visible = 'on'; drawnow;
            try
                app.LicTable = delimited2table(githubGet(app.UserName, app.RepoName, 'license-database.csv', 'read', Token = app.Token));
                app.LicTable(strcmp(app.LicTable.LicenseKey, ''), :) = [];
                app.LicensesUITable.Data = app.LicTable;
                app.LastRetrieveLabel.Text = sprintf('Last retrieved at %s.', datetime('now', Format = 'HH:mm:ss dd/MM/yyyy'));
            catch
                app.RetrieveDatabaseButton.Enable = 'off';
                app.LicensesUITable.Data = [];
            end
            app.LoadingImage.Visible = 'off'; drawnow;
        end

        function retrieveAppCredentials(app)
            FolderDir = fullfile(userpath, 'MATLAB App License Manager');
            FileDir = fullfile(FolderDir, 'repository-credentials.txt');
            try
                Credentials = readcell(FileDir);  
                if isempty(Credentials)
                    app.CredentialsDropDown.Items = {};
                    return;
                end
            catch
                app.CredentialsDropDown.Items = {};
                return;
            end
            app.CredentialList = unique(string(Credentials), 'row');
            app.CredentialsDropDown.Items = app.CredentialList(:, 1);
            app.CredentialsDropDown.ItemsData = 1 : numel(app.CredentialsDropDown.Items);
            app.CredentialsDropDown.Enable = 'on';
            CredentialsDropDownValueChanged(app);
            RepositoryEditFieldValueChanged(app);
            PATEditFieldValueChanged(app);
        end

    end

    
    methods (Access = public)
        
        function addLicense(app)
          
            app.LicTable = [app.LicTable; app.AddedLicense];
            FolderDir = makeAppFolder('MATLAB App License Manager');
            LicFilePath = fullfile(FolderDir, 'license-database.csv');
            writetable(app.LicTable, LicFilePath);
            githubPut(app.UserName, app.RepoName, 'license-database.csv',  LicFilePath, ...
                app.Token, CommitMessage = sprintf('License %s added', app.AddedLicense{1}));
            writematrix('', LicFilePath);
            tmpFilePath = fullfile(FolderDir, 'tmp');
            movefile(LicFilePath, tmpFilePath);
            delete(tmpFilePath);
            retrieveDatabase(app);
      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppWindow(app);
            app.DatabaseButtonGroup = [app.CreateDatabaseButton, app.RetrieveDatabaseButton, app.UpdateDatabaseButton];
            retrieveAppCredentials(app);
        end

        % Button pushed function: CreateDatabaseButton
        function CreateDatabaseButtonPushed(app, event)
            app.LoadingImage.Visible = 'on';
            drawnow;
            [Status, Details]  = createLicenseDatabase(app.UserName, app.RepoName, app.Token);
            app.LoadingImage.Visible = 'off';
            drawnow;
            if Status
                uialert(app.UIFigure, ['The icense database has been created at ', Details.content.html_url], 'Success', Icon = 'success');
                toggleRepoButtons(app);
            else
                uialert(app.UIFigure, {'The creation of license database has failed. Possible issues:', ...
                    '• ''license-database.csv'' already exists in the repository.', ...
                    '• Internet connection or GitHub server errors.'}, ...
                    'Fail', Icon = 'error');
            end
        end

        % Value changed function: RepositoryEditField
        function RepositoryEditFieldValueChanged(app, event)
            app.UserName = extractBefore(app.RepositoryEditField.Value, "/");
            app.RepoName = extractAfter(app.RepositoryEditField.Value, "/");
            checkRepoStatus(app);
        end

        % Value changed function: PATEditField
        function PATEditFieldValueChanged(app, event)
            app.Token = app.PATEditField.Value;
            checkRepoStatus(app);
        end

        % Value changing function: RepositoryEditField
        function RepositoryEditFieldValueChanging(app, event)
            RepositoryEditFieldValueChanged(app, event);
        end

        % Value changing function: PATEditField
        function PATEditFieldValueChanging(app, event)
            PATEditFieldValueChanged(app, event);
        end

        % Button pushed function: RetrieveDatabaseButton
        function RetrieveDatabaseButtonPushed(app, event)
            retrieveDatabase(app);
        end

        % Button pushed function: AddLicenseButton
        function AddLicenseButtonPushed(app, event)
            EditLicense(app, 'add');
        end

        % Button pushed function: SaveCredentialsButton
        function SaveCredentialsButtonPushed(app, event)
            Credentials.Repository = app.RepositoryEditField.Value;
            Credentials.PAT = app.Token;
            SaveCredentialsApp = SaveCredentials(app, Credentials);
            waitfor(SaveCredentialsApp);
            retrieveAppCredentials(app);
            app.CredentialsDropDown.Value = app.CredentialsDropDown.ItemsData(end);
        end

        % Value changed function: CredentialsDropDown
        function CredentialsDropDownValueChanged(app, event)
            Selected = app.CredentialsDropDown.Value;
            if numel(app.CredentialsDropDown.Items) == 1
                Selected = 1;
            end
            app.RepositoryEditField.Value = app.CredentialList(Selected, 2);
            RepositoryEditFieldValueChanged(app);
            app.PATEditField.Value= app.CredentialList(Selected, 3);
            PATEditFieldValueChanged(app);
        end

        % Drop down opening function: CredentialsDropDown
        function CredentialsDropDownOpening(app, event)
            if numel(app.CredentialsDropDown.Items) == 1
                CredentialsDropDownValueChanged(app);
            end
        end

        % Button pushed function: ClearCredentialListButton
        function ClearCredentialListButtonPushed(app, event)
             FolderDir = fullfile(userpath, 'MATLAB App License Manager');
             FileDir = fullfile(FolderDir, 'repository-credentials.txt');
             delete(FileDir);
             retrieveAppCredentials(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App License Manager';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'repo-key-icon.png');
            app.DatabasePanel = uipanel(app.UIFigure);
            app.DatabasePanel.Title = 'Database';
            app.CreateDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.CreateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDatabaseButtonPushed, true);
            app.CreateDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-plus-outline.png');
            app.CreateDatabaseButton.Enable = 'off';
            app.CreateDatabaseButton.Tooltip = {'Create Database'};
            app.CreateDatabaseButton.Text = '';
            app.UpdateDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.UpdateDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-arrow-up-outline.png');
            app.UpdateDatabaseButton.Enable = 'off';
            app.UpdateDatabaseButton.Tooltip = {'Update Database'};
            app.UpdateDatabaseButton.Text = '';
            app.RetrieveDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.RetrieveDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveDatabaseButtonPushed, true);
            app.RetrieveDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-refresh-outline.png');
            app.RetrieveDatabaseButton.Enable = 'off';
            app.RetrieveDatabaseButton.Tooltip = {'Retrieve Database'};
            app.RetrieveDatabaseButton.Text = '';
            app.RepositoryEditField = uieditfield(app.DatabasePanel, 'text');
            app.RepositoryEditField.ValueChangedFcn = createCallbackFcn(app, @RepositoryEditFieldValueChanged, true);
            app.RepositoryEditField.ValueChangingFcn = createCallbackFcn(app, @RepositoryEditFieldValueChanging, true);
            app.RepositoryEditField.Tooltip = {'The repo that you created to store the license database of the app'};
            app.RepositoryEditField.Placeholder = 'username/repo-name';
            app.PATEditField = uieditfield(app.DatabasePanel, 'text');
            app.PATEditField.ValueChangedFcn = createCallbackFcn(app, @PATEditFieldValueChanged, true);
            app.PATEditField.ValueChangingFcn = createCallbackFcn(app, @PATEditFieldValueChanging, true);
            app.PATEditField.Tooltip = {'Personal Access Token'};
            app.PATEditField.Placeholder = 'Personal Access Token';
            app.RepoImage = uiimage(app.DatabasePanel);
            app.RepoImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'folder-arrow-up-down-outline.png');
            app.PATImage = uiimage(app.DatabasePanel);
            app.PATImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'shield-key-outline.png');
            app.SaveCredentialsButton = uibutton(app.DatabasePanel, 'push');
            app.SaveCredentialsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCredentialsButtonPushed, true);
            app.SaveCredentialsButton.Icon = fullfile(pathToMLAPP, 'graphics', 'content-save-outline.png');
            app.SaveCredentialsButton.Enable = 'off';
            app.SaveCredentialsButton.Tooltip = {'Save Credentials'};
            app.SaveCredentialsButton.Text = '';
            app.RepoCheckImage = uiimage(app.DatabasePanel);
            app.RepoCheckImage.Visible = 'off';
            app.RepoCheckImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'check-circle.png');
            app.LicensesPanel = uipanel(app.UIFigure);
            app.LicensesPanel.Title = 'Licenses';
            app.AddLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.AddLicenseButton.ButtonPushedFcn = createCallbackFcn(app, @AddLicenseButtonPushed, true);
            app.AddLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-plus-outline.png');
            app.AddLicenseButton.Enable = 'off';
            app.AddLicenseButton.Tooltip = {'Add License'};
            app.AddLicenseButton.Text = '';
            app.EditLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.EditLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-edit-outline.png');
            app.EditLicenseButton.Enable = 'off';
            app.EditLicenseButton.Tooltip = {'Edit License'};
            app.EditLicenseButton.Text = '';
            app.RemoveLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.RemoveLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-remove-outline.png');
            app.RemoveLicenseButton.Enable = 'off';
            app.RemoveLicenseButton.Tooltip = {'Delete License'};
            app.RemoveLicenseButton.Text = '';
            app.SearchLicenseEditField = uieditfield(app.LicensesPanel, 'text');
            app.SearchLicenseEditField.Tooltip = {''};
            app.SearchLicenseEditField.Placeholder = 'Search License';
            app.SearchLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.SearchLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-search-icon.png');
            app.SearchLicenseButton.Tooltip = {'Search License'};
            app.SearchLicenseButton.Text = '';
            app.LicensesUITable = uitable(app.LicensesPanel);
            app.LicensesUITable.ColumnName = {'License Key'; 'User Email'; 'Valid Until'; 'Device ID'};
            app.LicensesUITable.RowName = {};
            app.LicensesUITable.SelectionType = 'row';
            app.LoadingImage = uiimage(app.LicensesPanel);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'loading-icon.gif');
            app.RemoveLicenseButton_2 = uibutton(app.LicensesPanel, 'push');
            app.RemoveLicenseButton_2.Icon = fullfile(pathToMLAPP, 'graphics', 'email-fast-outline.png');
            app.RemoveLicenseButton_2.Enable = 'off';
            app.RemoveLicenseButton_2.Tooltip = {'Delete License'};
            app.RemoveLicenseButton_2.Text = '';
            app.CredentialsDropDown = uidropdown(app.UIFigure);
            app.CredentialsDropDown.Items = {};
            app.CredentialsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CredentialsDropDownOpening, true);
            app.CredentialsDropDown.ValueChangedFcn = createCallbackFcn(app, @CredentialsDropDownValueChanged, true);
            app.CredentialsDropDown.Enable = 'off';
            app.CredentialsDropDown.Placeholder = 'Select App Credentials';
            app.CredentialsDropDown.Value = {};
            app.LastRetrieveLabel = uilabel(app.UIFigure);
            app.LastRetrieveLabel.Text = 'Never retrieved.';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'progress-clock.png');
            app.ClearCredentialListButton = uibutton(app.UIFigure, 'push');
            app.ClearCredentialListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCredentialListButtonPushed, true);
            app.ClearCredentialListButton.Icon = fullfile(pathToMLAPP, 'graphics', 'trash-can-outline.png');
            app.ClearCredentialListButton.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.PATHelp = uimenu(app.ContextMenu);
            app.PATHelp.Text = 'What''s this?';
            % Assign app.ContextMenu
            app.PATEditField.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LicenseManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end