classdef SaveCredentials < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveCredentialsButton     matlab.ui.control.Button
        PATEditField              matlab.ui.control.EditField
        PATEditFieldLabel         matlab.ui.control.Label
        RepositoryEditField       matlab.ui.control.EditField
        RepositoryEditFieldLabel  matlab.ui.control.Label
        AppNameEditField          matlab.ui.control.EditField
        AppNameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        MainApp
        Credentials
        AppName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Credentials)
            centerAppWindow(app);
            focus(app.AppNameEditField);
            app.MainApp = MainApp;
            app.Credentials = Credentials;
            app.RepositoryEditField.Value = app.Credentials.Repository;
            app.PATEditField.Value = app.Credentials.PAT;
        end

        % Value changed function: AppNameEditField
        function AppNameEditFieldValueChanged(app, event)
            if ~strcmp(event.Value, '')
               app.SaveCredentialsButton.Enable = 'on'; 
               focus(app.SaveCredentialsButton);
            else
               app.SaveCredentialsButton.Enable = 'off'; 
            end
            app.AppName = app.AppNameEditField.Value;
        end

        % Value changing function: AppNameEditField
        function AppNameEditFieldValueChanging(app, event)

        end

        % Button pushed function: SaveCredentialsButton
        function SaveCredentialsButtonPushed(app, event)
            FolderDir = fullfile(userpath, 'MATLAB App License Manager');
            mkdir(FolderDir);
            FileDir = fullfile(FolderDir, 'repository-credentials.txt');
            FileID = fopen(FileDir, 'a+');
            fprintf(FileID, '%s,%s,%s\n', app.AppName, app.Credentials.Repository, app.Credentials.PAT);
            fclose('all');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Save Credentials';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'repo-key-icon.png');
            app.AppNameEditFieldLabel = uilabel(app.UIFigure);
            app.AppNameEditFieldLabel.Text = 'App Name';
            app.AppNameEditField = uieditfield(app.UIFigure, 'text');
            app.AppNameEditField.ValueChangedFcn = createCallbackFcn(app, @AppNameEditFieldValueChanged, true);
            app.AppNameEditField.ValueChangingFcn = createCallbackFcn(app, @AppNameEditFieldValueChanging, true);
            app.RepositoryEditFieldLabel = uilabel(app.UIFigure);
            app.RepositoryEditFieldLabel.Text = 'Repository';
            app.RepositoryEditField = uieditfield(app.UIFigure, 'text');
            app.RepositoryEditField.Editable = 'off';
            app.PATEditFieldLabel = uilabel(app.UIFigure);
            app.PATEditFieldLabel.Text = 'PAT';
            app.PATEditField = uieditfield(app.UIFigure, 'text');
            app.PATEditField.Editable = 'off';
            app.SaveCredentialsButton = uibutton(app.UIFigure, 'push');
            app.SaveCredentialsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCredentialsButtonPushed, true);
            app.SaveCredentialsButton.Icon = fullfile(pathToMLAPP, 'graphics', 'content-save-outline.png');
            app.SaveCredentialsButton.Enable = 'off';
            app.SaveCredentialsButton.Text = 'Save Credentials';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveCredentials(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end