classdef appdeactivation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DeactivationiscompulsorybeforeuninstallingtheappLabel  matlab.ui.control.Label
        LicenseInfoTextArea  matlab.ui.control.TextArea
        DeactivateButton     matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp;
        AppName;
        UserName;
        RepoName;
        Token;
        AppIcon;
        Lic;
        DeviceID;
        RedHexCode = '#d91f1f';
    end

    methods (Access = private)

        function [IsCloseSelected, LicenseInfo] = getLicenseInfo(app)
            IsCloseSelected = false;
            app.DeviceID = generateDeviceId;
            MainAppUIFigure = findUIFigure(app.MainApp);
            app.AppName = MainAppUIFigure.Name;
            try app.Lic = delimited2table(githubGet(app.UserName, app.RepoName, 'license-database.csv', Token = app.Token));
            catch Exception    % Catch Internet connection error
                if contains(Exception.identifier, 'connection', IgnoreCase = true)
                    ErrorMsg = 'Error connecting to the Internet.';
                else
                    ErrorMsg = 'Unknown error sources.';
                end
                Selection = uiconfirm(MainAppUIFigure, ErrorMsg, app.AppName, ...
                    Options = {'Try Again', 'Close'}, Icon = 'error');  
                switch Selection
                    case 'Try Again'
                        [IsCloseSelected, LicenseInfo] = getLicenseInfo(app);
                    case 'Close'
                        IsCloseSelected = true;
                end
            end
            MatchedDeviceIdx = find(app.Lic.DeviceID == app.DeviceID);
            LicenseInfo(1) = compose("License Key\t: %s", app.Lic.LicenseKey(MatchedDeviceIdx));
            LicenseInfo(2) = compose("Device ID\t: %s", app.DeviceID);
            LicenseInfo(3) = compose("Email\t\t: %s", app.Lic.UserEmail(MatchedDeviceIdx));
            LicenseInfo(4) = compose("Valid Until\t: %s", app.Lic.ValidUntil(MatchedDeviceIdx));
        end

        function warningUIAlert(app, Message)
            Selection = uiconfirm(app.UIFigure, Message, 'Unsuccessful Deativation', Icon = 'warning', Options = {'Try again', 'Close'});
            if strcmp(Selection, 'Close')
                UIFigureCloseRequest(app);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, UserName, RepoName, Token)
            arguments
                app
                MainApp
                UserName
                RepoName
                Token
            end
            centerAppWindow(app);
            app.MainApp = MainApp;
            MainAppUIFigure = findUIFigure(app.MainApp);
            app.AppName = MainAppUIFigure.Name;
            app.UserName = UserName;
            app.RepoName = RepoName;
            app.Token = Token;
            app.UIFigure.Name = 'License Info';
            if ~isempty(MainAppUIFigure.Icon)
                app.UIFigure.Icon = MainAppUIFigure.Icon;
            end
            [IsCloseSelected, LicenseInfo] = getLicenseInfo(app);
            if IsCloseSelected
                delete(app);
                return;
            end
            app.LicenseInfoTextArea.Value = LicenseInfo;
            focus(app.DeactivateButton);
        end

        % Callback function
        function LicenseKeyEditFieldValueChanged(app, event)
            app.IsLicenseFormatValid = checkLicenseFormatValidity(app, app.LicenseKeyEditField.Value);
            if app.IsLicenseFormatValid
                app.LicenseText.Visible = 'off';
                focus(app.EmailAddressEditField);
            else
                app.LicenseText.Visible = 'on';
            end
            if isempty(app.LicenseKeyEditField.Value)
                app.LicenseText.Visible = 'off';
            end
            app.LicenseKeyEditField.Value = upper(app.LicenseKeyEditField.Value);
            enableActivateButton(app);
        end

        % Callback function
        function EmailAddressEditFieldValueChanged(app, event)
            app.IsEmailFormatValid = checkEmailFormatValidity(app, app.EmailAddressEditField.Value);
            if app.IsEmailFormatValid
                app.EmailText.Visible = 'off';
            else
                app.EmailText.Visible = 'on';
            end
            if isempty(app.EmailAddressEditField.Value)
                app.EmailText.Visible = 'off';
            end
            enableActivateButton(app);
        end

        % Button pushed function: DeactivateButton
        function DeactivateButtonPushed(app, event)
            Selection = uiconfirm(app.UIFigure, {'This device will be unassigned from the license.'; ...
                'You will need to activate the app to use it again.';
                'Proceed only if you need to use the license on another device.'}, 'App Deactivation', ...
                Options = {'Cancel', 'Proceed'});
           if strcmp(Selection, 'Cancel')
               return;        
           end
           ProgressDlg = uiprogressdlg(app.UIFigure, Indeterminate = true, Message = 'Deactivating app..');
           MatchedDeviceIdx = find(app.Lic.DeviceID == app.DeviceID);
           app.Lic.DeviceID(MatchedDeviceIdx) = '';
           writetable(app.Lic, 'license-database.csv'); % (!) WIP - POSSIBLE VULNERABILITY
           try githubPut(app.UserName, app.RepoName, 'license-database.csv', 'license-database.csv', ...
                   app.Token, CommitMessage = sprintf('Device %s deactivated', app.DeviceID));
           catch ME
               close(ProgressDlg);
               warningUIAlert(app, 'App deactivation unsuccessful.');
               return;
           end
            % Clear the contents of the 'license-database' file and delete
            writematrix('', 'license-database.csv');
            movefile('license-database.csv', 'tmp');
            delete('tmp');
            close(ProgressDlg);
            Selection = uiconfirm(app.UIFigure, 'App has been deactivated!', 'Successful Deactivation', Icon = 'success', Options = 'Close'); %#ok<NASGU> 
            delete(app.MainApp)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DeactivateButton = uibutton(app.UIFigure, 'push');
            app.DeactivateButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateButtonPushed, true);
            app.DeactivateButton.Text = 'Deactivate';
            app.LicenseInfoTextArea = uitextarea(app.UIFigure);
            app.LicenseInfoTextArea.Editable = 'off';
            app.DeactivationiscompulsorybeforeuninstallingtheappLabel = uilabel(app.UIFigure);
            app.DeactivationiscompulsorybeforeuninstallingtheappLabel.Text = '🛈 Deactivation is compulsory before uninstalling the app.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appdeactivation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end